classdef MatlabSifRead < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main                    matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenFileMenu            matlab.ui.container.Menu
        Save0asMenu             matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        ListMenu                matlab.ui.container.Menu
        AddFileinHeadMenu       matlab.ui.container.Menu
        AddFileinEnddingMenu    matlab.ui.container.Menu
        ReplaceFileListMenu     matlab.ui.container.Menu
        DeleteCurrentFileMenu   matlab.ui.container.Menu
        DeleteAllFileMenu       matlab.ui.container.Menu
        WindowsMenu             matlab.ui.container.Menu
        CloseAllWindowsMenu     matlab.ui.container.Menu
        ProcessMenu             matlab.ui.container.Menu
        SubtractBackgroundMenu  matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        MainTab                 matlab.ui.container.Tab
        TabGroup2               matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        Panel_2                 matlab.ui.container.Panel
        OKButton_2              matlab.ui.control.Button
        YminEditFieldLabel      matlab.ui.control.Label
        YminEditField           matlab.ui.control.NumericEditField
        YmaxEditFieldLabel      matlab.ui.control.Label
        YmaxEditField           matlab.ui.control.NumericEditField
        Panel_3                 matlab.ui.container.Panel
        OKButton                matlab.ui.control.Button
        XminEditFieldLabel      matlab.ui.control.Label
        XminEditField           matlab.ui.control.NumericEditField
        XmaxEditFieldLabel      matlab.ui.control.Label
        XmaxEditField           matlab.ui.control.NumericEditField
        UIAxes                  matlab.ui.control.UIAxes
        Panel_4                 matlab.ui.container.Panel
        CommandLineButton_2     matlab.ui.control.StateButton
        runButton               matlab.ui.control.Button
        Panel_5                 matlab.ui.container.Panel
        SaveButton              matlab.ui.control.Button
        CommandLinePanel        matlab.ui.container.Panel
        EditField               matlab.ui.control.EditField
        ExecuteButton           matlab.ui.control.Button
        SetTab                  matlab.ui.container.Tab
        CalculationPanel        matlab.ui.container.Panel
        DropDown_2              matlab.ui.control.DropDown
        DropDown_3              matlab.ui.control.DropDown
        Label                   matlab.ui.control.Label
        DropDown_4              matlab.ui.control.DropDown
        ApplyButton             matlab.ui.control.Button
        Label_2                 matlab.ui.control.Label
        DropDown_6              matlab.ui.control.DropDown
        EditField_2             matlab.ui.control.NumericEditField
        HoldButton              matlab.ui.control.StateButton
        Panel                   matlab.ui.container.Panel
        OpenButton              matlab.ui.control.Button
        ClearButton             matlab.ui.control.Button
        PlotButton              matlab.ui.control.Button
        SifListListBoxLabel     matlab.ui.control.Label
        SifListListBox          matlab.ui.control.ListBox
        CloseAllButton          matlab.ui.control.Button
        SaveButton_2            matlab.ui.control.Button
        CMDButton               matlab.ui.control.Button
    end


    properties (Access = private)
         % Description
        number = 1
        lineNumber = 1
        move = 50
        newMenu             matlab.ui.container.Menu    % 新菜单
        newTab              matlab.ui.container.Tab     % 新窗口
        EditField1          matlab.ui.control.EditField
        
        data
        pattern1
        calibvals1
        xtype1
        xunit1
        ytype1 
    end

    properties (Access = public)
         alreadyPlotList = ""   % 已经打开的文件列表
         position               %  主窗口的位置
         xMain                  %  主窗口的位置x
         yMain                  % 主窗口的位置y
         width                  % 主窗口的位置宽
         height                 % 主窗口的位置高
         sifOpen                % 打开sif文件的对象
         sifPlot                % 画sif图像的对象
         showStr = ""           % 显示打开的窗口列表
         lineObj1               % 线对象
         lineObj2               % 线对象
         lineObj3               % 线对象
         lineObj4               % 线对象
         lineObj5               % 线对象
         lineObj6               % 线对象
    end

    methods (Access = public)
    
        function fileName = FileName(app)
            % 获取选中列表的文件名
            value = app.SifListListBox.Value;
            numberValue = app.sifOpen.StrPositionInList(app.sifOpen.fullFilePath,value);
            fileName = app.sifOpen.fileName(numberValue);
        end
    
        
        function   CloseChooseWindow(app, event)
            % 关闭窗口
            S = get(app.newMenu,'Text');
            
            
        end
        
        function  AllDataInit(app)
            % 清除储存的数据
            app.sifPlot.allPattern = "";
            app.sifPlot.allCalibvals = [];
            app.sifPlot.allData = [];
            app.sifPlot.allXtype  = "";
            app.sifPlot.allXunit  = "" ;
            app.sifPlot.allYtype  = "";
            app.sifPlot.allYunit  = "" ;
            app.sifPlot.allImageData = [];
            app.sifPlot.number = 1;
        end
        
    end

    methods (Access = private)
    
        function SaveFileToAlreadyPlot(app,fileName)
            % 保存文件到已经打开文件列表
            size = length(app.alreadyPlotList);
                % 把打开的文件名保存起来
                if app.alreadyPlotList(1) == ""
                    app.alreadyPlotList(1) = fileName;
                else
                    app.alreadyPlotList(size + 1) = fileName;
                end
        end
        
        function NewTabWidows(app,fileName)
            % 打开新的窗口
            
            % 设置标题,新建窗口
            app.newTab(app.number) = uitab(app.TabGroup2);
            str = string(app.number);
            app.newTab(app.number).Title = strcat("#",str);
            app.number = app.number + 1;
            
            % 新建标签，显示文件名
            label = uilabel(app.newTab(app.number-1));
            label.Position = [0.4*app.width 0.82*app.height 0.4*app.width 0.03*app.height];
            label.Text = fileName;
            label.FontSize = 16;
            
            % 新建坐标轴
            axes = uiaxes(app.newTab(app.number-1));
            axes.Position = [0.03*app.width 0.05*app.height 0.9*app.width 0.75*app.height];
            cellFilePath = app.SifListListBox.Value;
            strFilePath = string(cellFilePath);
            size = length(strFilePath);
            if size == 1
                 % 画单个图像
                charFilePath = char(strFilePath);
                % 获取数据
                app.sifPlot.GetSifData(charFilePath);
                
                app.sifPlot.SifPlot(axes,app.number-1);              
            else
                % 画多个图像
                for i = 1:size
                    charFilePath = char(strFilePath(i));
                    app.sifPlot.GetSifData(charFilePath);
                    app.sifPlot.SifPlot(axes,app.number-1);                   
                end
            end
            axes.Box = 'on';               
        end
    
        function NewMenu(app,fileName)
            % 创建新的按钮
            app.newMenu(app.number-1) = uimenu(app.WindowsMenu);
            str = string(app.number-1);
            app.newMenu(app.number-1).Text = strcat("#",str,"  --  ", fileName);
            app.showStr(app.number) = strcat("#",str,"  --  ", fileName);
            app.newMenu(app.number-1).MenuSelectedFcn = createCallbackFcn(app,@CloseChooseWindow,true);
        end
    
        function PlotSingleSifFile(app, strFileName)
            % 画单个文件图像
            
            
            % 获取选中列表的文件名
            % value = app.SifListListBox.Value;
            if length(app.sifOpen.fullFilePath) == 1
                numberValue = 1;
            elseif isempty(app.sifOpen.fullFilePath)
                % 提示请选择文件
                text = "请选择的您要打开的文件！";
                DisplayPromptMessage(app.Main,"警告",text);  
                return
            else
                numberValue = app.sifOpen.StrPositionInList(app.sifOpen.fullFilePath,strFileName);
            end
            fileName = app.sifOpen.fileName(numberValue);
            
            % 判断文件是否被打开
            status = app.sifOpen.StrPositionInList(app.alreadyPlotList,fileName);
            if status == 0

                % 把打开的文件名保存起来
                app.SaveFileToAlreadyPlot(fileName);
                
                % 新建窗口显示图画
                app.NewTabWidows(fileName);   
                
                % 新建菜单来关闭窗口
                app.NewMenu(fileName);
                list = ["#0",app.showStr(2:end)];     
                app.DropDown_2.Items = list;
                app.DropDown_3.Items = app.showStr;
            else
               % 提示已经打开了
                text = "您选择的文件已经打开！";
                DisplayPromptMessage(app.Main,"警告",text);  
            end
        end
        
        function  GetSifDataToCalculation(app,uiaxes,filePath1,filePath2,number1,operator1,operator2)
            % 获取数据
            % uiaxes 为画的坐标轴
            % filePath1 为文件名，filePath2 为文件名或者 数字
            % operator 为运算符
            
            if filePath1 == 0
                data1 = app.data;
            else
                [app.pattern1,app.calibvals1,data1,app.xtype1,app.xunit1,...
                    app.ytype1,yunit1] = MatlabUI_sif_show(filePath1);
            end
            
            if filePath2 == 0
                data2 = 0;
                pattern2 = app.pattern1;
                calibvals2 = app.calibvals1;
            else
                [pattern2,calibvals2,data2,xtype2,xunit2,...
                         ytype2,yunit2] = MatlabUI_sif_show(filePath2);
            end
            
             % 获取hold按钮的值,如果为零，则清除无效的线对象 
             value = app.HoldButton.Value; 
             if value == 0
                 app.lineNumber = 1;
             end
            
            if app.pattern1 == pattern2
                if app.pattern1 == '0'
                    if app.calibvals1 == calibvals2
                        % 画出信号图
                        app.CalculationPlot(data1,data2,number1,operator1,operator2);
                        
                        if app.lineNumber == 1
                            app.lineObj1 = app.sifPlot.SignalPlot(uiaxes,app.calibvals1,...
                                app.data,app.xtype1,app.xunit1,app.ytype1);
                        elseif app.lineNumber == 2
                            app.lineObj2 = app.sifPlot.SignalPlot(uiaxes,app.calibvals1,...
                                app.data,app.xtype1,app.xunit1,app.ytype1);
                        elseif app.lineNumber == 3
                            app.lineObj3 = app.sifPlot.SignalPlot(uiaxes,app.calibvals1,...
                                app.data,app.xtype1,app.xunit1,app.ytype1);
                        elseif app.lineNumber == 4
                            app.lineObj4 = app.sifPlot.SignalPlot(uiaxes,app.calibvals1,...
                                app.data,app.xtype1,app.xunit1,app.ytype1);
                        elseif app.lineNumber == 5
                            app.lineObj5 = app.sifPlot.SignalPlot(uiaxes,app.calibvals1,...
                                app.data,app.xtype1,app.xunit1,app.ytype1);
                        elseif app.lineNumber == 6
                            app.lineObj6 = app.sifPlot.SignalPlot(uiaxes,app.calibvals1,...
                                app.data,app.xtype1,app.xunit1,app.ytype1);
                        end
                        app.lineNumber = app.lineNumber + 1;
                    else
                        % 报错,显示数据长度不同
                        str = "数据长度不同";
                        DisplayPromptMessage(app.Main,"错误",str);
                    end
                elseif app.pattern1 == '4'
                    % 显示图像
                    app.CalculationPlot(data1,data2,number1,operator1,operator2);
                    
                    if app.lineNumber == 1
                        app.lineObj1 = app.sifPlot.ImageShow(uiaxes,app.data,app.xtype1,app.ytype1);
                    elseif app.lineNumber == 2
                        app.lineObj2 = app.sifPlot.ImageShow(uiaxes,app.data,app.xtype1,app.ytype1);
                    elseif app.lineNumber == 3
                        app.lineObj3 = app.sifPlot.ImageShow(uiaxes,app.data,app.xtype1,app.ytype1);
                    elseif app.lineNumber == 4
                        app.lineObj4 = app.sifPlot.ImageShow(uiaxes,app.data,app.xtype1,app.ytype1);
                    elseif app.lineNumber == 5
                        app.lineObj5 = app.sifPlot.ImageShow(uiaxes,app.data,app.xtype1,app.ytype1);
                    elseif app.lineNumber == 6
                        app.lineObj6 = app.sifPlot.ImageShow(uiaxes,app.data,app.xtype1,app.ytype1);
                    end
                    
                    app.lineNumber = app.lineNumber + 1;
                    
                end
                
            else
                % 报错，显示类型不同
                str = "数据类型不同";
                DisplayPromptMessage(app.Main,"错误",str);
            end
                
                    
        end
        
        function CalculationPlot(app,data1,data2,number1,operator1,operator2)
            % 对数据处理
            strOperator1 = string(operator1);
            strOperator2 = string(operator2);
            
            if strOperator1 == "-"
                if strOperator2 == "-"          
                    app.data = data1 - data2 - number1;
                elseif strOperator2 == "+"
                    app.data = data1 - data2 + number1;
                elseif strOperator2 == "x"
                    app.data = (data1 - data2) .* number1;
                elseif strOperator2 == "/"
                    app.data = (data1 - data2) ./ number1;
                end
            elseif strOperator1 == "+"
                if strOperator2 == "-"          
                    app.data = data1 + data2 - number1;
                elseif strOperator2 == "+"
                    app.data = data1 + data2 + number1;
                elseif strOperator2 == "x"
                    app.data = (data1 + data2) .* number1;
                elseif strOperator2 == "/"
                    app.data = (data1 + data2) ./ number1;
                end
            elseif strOperator1 == "x"
                if strOperator2 == "-"          
                    app.data = data1 .* data2 - number1;
                elseif strOperator2 == "+"
                    app.data = data1 .* data2 + number1;
                elseif strOperator2 == "x"
                    app.data = (data1 .* data2) .* number1;
                elseif strOperator2 == "/"
                    app.data = (data1 .* data2) ./ number1;
                end
            elseif strOperator1 == "/"
                if strOperator2 == "-"          
                    app.data = data1 ./ data2 - number1;
                elseif strOperator2 == "+"
                    app.data = data1 ./ data2 + number1;
                elseif strOperator2 == "x"
                    app.data = (data1 ./ data2) .* number1;
                elseif strOperator2 == "/"
                    app.data = (data1 ./ data2) ./ number1;
                end
            end
        end
         
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 初始化函数
            app.sifOpen = MatlabSifOpen();      % 打开文件类的实例
            app.sifPlot = MatlabSifPlot();      % 画图类的实例
        end

        % Callback function: OpenButton, OpenFileMenu
        function OpenButtonPushed(app, event)
            % 添加文件到列表
            app.sifOpen.AddFileToList(0);
            app.SifListListBox.Items = app.sifOpen.fullFilePath;
        end

        % Size changed function: Main
        function MainSizeChanged(app, event)
             app.position= app.Main.Position; 
             app.xMain = app.position(1);
             app.yMain = app.position(2);
             app.width = app.position(3);
             app.height = app.position(4);
             
             % 设置各个组件的大小
             tabGroupHeight = 683/775*app.height;
             app.TabGroup.Position = [1 1 app.width tabGroupHeight];
             app.TabGroup2.Position = [1 1 app.width 0.97*tabGroupHeight];
             app.UIAxes.Position = [0.02*app.width 0.02*app.height 0.80*app.width 0.8*app.height];
             app.Panel_3.Position = [0.83*app.width 0.61*app.height 0.12*app.width 0.16*app.height];   
             app.Panel_2.Position = [0.83*app.width 0.44*app.height 0.12*app.width 0.16*app.height];  
             app.Panel_5.Position = [0.83*app.width 0.27*app.height 0.12*app.width 0.16*app.height];  
             app.Panel_4.Position = [0.83*app.width 0.10*app.height 0.12*app.width 0.16*app.height]; 
             app.CommandLinePanel.Position = [0.03*app.width 0.7*app.height 0.34*app.width 0.15*app.height]; 
             
             
             panelButton = tabGroupHeight + 1;
             panelHeight = 93/775*app.height;
             app.Panel.Position = [1 panelButton app.width panelHeight];
             
             % 设置列表框的大小
             app.SifListListBoxLabel.Position = [0.24*app.width 0.55*panelHeight 0.05*app.width 0.4*panelHeight];
             app.SifListListBox.Position = [0.295*app.width 0.1*panelHeight 0.7*app.width 0.8*panelHeight];
             
             % 设置按钮的大小
             buttonWidth = 0.05;
             buttonHeight = 0.4;
             app.OpenButton.Position = [0.003*app.width 0.55*panelHeight buttonWidth*app.width buttonHeight*panelHeight];           
             app.PlotButton.Position = [0.003*app.width 0.05*panelHeight buttonWidth*app.width buttonHeight*panelHeight];
             
             app.ClearButton.Position = [0.056*app.width 0.55*panelHeight buttonWidth*app.width buttonHeight*panelHeight];
             app.SaveButton_2.Position = [0.056*app.width 0.05*panelHeight buttonWidth*app.width buttonHeight*panelHeight];
             
             app.CloseAllButton.Position = [0.109*app.width 0.55*panelHeight 1.2*buttonWidth*app.width buttonHeight*panelHeight];
             app.CMDButton.Position = [0.109*app.width 0.05*panelHeight 1.2*buttonWidth*app.width buttonHeight*panelHeight];
             % app.CloseDropDownLabel.Position = [0.106*app.width 0.55*panelHeight 0.9*buttonWidth*app.width buttonHeight*panelHeight];
             % app.CloseDropDown.Position = [0.155*app.width 0.55*panelHeight 1.3*buttonWidth*app.width buttonHeight*panelHeight];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % 画图
            value = app.SifListListBox.Value;
            strFileName = string(value);
            size = length(strFileName);
            if size == 1
                 % 画单个图像
                app.PlotSingleSifFile(strFileName);
            else
                % 画多个图像
                for i = 1:size
                    app.PlotSingleSifFile(strFileName(i));
                end
            end
        end

        % Menu selected function: AddFileinHeadMenu
        function AddFileinHeadMenuSelected(app, event)
            % 添加文件到列表头部
            app.sifOpen.AddFileToList(0);
            app.SifListListBox.Items = app.sifOpen.fullFilePath;
        end

        % Menu selected function: AddFileinEnddingMenu
        function AddFileinEnddingMenuSelected(app, event)
            % 添加文件到列表头部
            app.sifOpen.AddFileToList(1);
            app.SifListListBox.Items = app.sifOpen.fullFilePath;
        end

        % Menu selected function: DeleteCurrentFileMenu
        function DeleteCurrentFileMenuSelected(app, event)
            % 删除选中的文件
            
            % 获取要删除的文件名
            value = app.SifListListBox.Value;
            
            % 找到它在列表中的位置，从列表中删除它
            num = app.sifOpen.StrPositionInList(app.sifOpen.fullFilePath,string(value));
            app.sifOpen.DeleteFile(0,num);
            app.SifListListBox.Items = app.sifOpen.fullFilePath;
        end

        % Menu selected function: DeleteAllFileMenu
        function DeleteAllFileMenuSelected(app, event)
            % 删除选中的文件
            app.sifOpen.DeleteFile(1,0);
            app.SifListListBox.Items = app.sifOpen.fullFilePath;
        end

        % Value changed function: SifListListBox
        function SifListListBoxValueChanged(app, event)
            value = app.SifListListBox.Value;
            
        end

        % Menu selected function: ReplaceFileListMenu
        function ReplaceFileListMenuSelected(app, event)
            % 添加文件到列表
            app.sifOpen.GetFileList();
            app.SifListListBox.Items = app.sifOpen.fullFilePath;
            AllDataInit(app);
        end

        % Callback function: CloseAllButton, CloseAllWindowsMenu
        function CloseAllWindowsMenuSelected(app, event)
            % 关闭所有的窗口
            delete(app.newTab);
            delete(app.newMenu);
            app.alreadyPlotList = "";
            app.showStr = "";
            app.DropDown_3.Items = app.showStr;
            app.DropDown_2.Items = app.showStr;
            
            app.number = 1;
            app.lineNumber = 1;
        end

        % Callback function
        function ButtonPushed(app, event)
%             % 画多个图像
%             % 画单个图像
%             value = app.SifListListBox.Value;
%             strFileName = string(value);
%             size = length(strFileName);
%             if size == 1
%                 app.PlotSingleSifFile(strFileName);
%             else
%                 for i = 1:size
%                     app.PlotSingleSifFile(strFileName(i));
%                 end
%             end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % 获取窗口名称
            operator1 = app.DropDown_4.Value;
            operator2 = app.DropDown_6.Value;
            
            % 获取第一个文件名
            value1 = app.DropDown_2.Value;
            if string(value1) == "#0"
                filePath1 = 0;
            else
                file1 = string(value1(9:end));
                if length(app.sifOpen.fullFilePath) == 1
                    number1 = 1;
                else
                    number1 = app.sifOpen.StrPositionInList(app.sifOpen.fullFilePath,file1);
                end
                filePath1 = char(app.sifOpen.fullFilePath(number1));
            end
            
            % 获取第二个文件名
            value2 = app.DropDown_3.Value;
            file2 = string(value2(9:end));
            number2 = app.sifOpen.StrPositionInList(app.sifOpen.fullFilePath,file2);
            if number2 == 0
                filePath2 = 0;
            else
                filePath2 = char(app.sifOpen.fullFilePath(number2));
            end
            
            % 获取数值框的值
            number1 = app.EditField_2.Value;
%             if number2 == 0
%                 filePath2 = str2double(value2);
%             else
%                 filePath2 = char(app.sifOpen.fullFilePath(number2));
%             end
            
            % 画图获取数据
            app.GetSifDataToCalculation(app.UIAxes,filePath1,filePath2,number1,operator1,operator2);
            
        end

        % Callback function
        function Button_2Pushed(app, event)
            % 放大图像横坐标
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % 调整x坐标
            xmin = app.XminEditField.Value;
            xmax = app.XmaxEditField.Value;
            app.UIAxes.XLim = [xmin xmax];
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)
            % 调整y坐标
            ymin = app.YminEditField.Value;
            ymax = app.YmaxEditField.Value;
            app.UIAxes.YLim = [ymin ymax];
        end

        % Value changed function: HoldButton
        function HoldButtonValueChanged(app, event)
            value = app.HoldButton.Value;
            if value == 1
                hold(app.UIAxes,'on');
            else
                hold(app.UIAxes,'off');
            end
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            [file,path] = uigetfile('.m');
            filePaths = fullfile(path,file);
            run(filePaths);
        end

        % Callback function: CMDButton, CommandLineButton_2
        function CommandLineButton_2ValueChanged(app, event)
            value = app.CommandLineButton_2.Value;
            if value == 1
                app.CommandLinePanel.Visible = 'on';
            else
                app.CommandLinePanel.Visible = 'off';
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            value = app.EditField.Value;
            fileID = fopen('commands.m','w');
            fprintf(fileID,value);
            fclose(fileID);
            run commands.m;
        end

        % Callback function: Save0asMenu, SaveButton, SaveButton_2
        function SaveButtonPushed(app, event)
            if app.pattern1 == '0'
                figure(3);
                grid on;
                % 调整x坐标
                xmin = app.XminEditField.Value;
                xmax = app.XmaxEditField.Value;
                if xmin < xmax
                    app.UIAxes.XLim = [xmin xmax];
                end
                 % 调整y坐标
                ymin = app.YminEditField.Value;
                ymax = app.YmaxEditField.Value;
                if ymin < ymax
                    app.UIAxes.YLim = [ymin ymax];
                end
                
                xlabel(app.UIAxes.XLabel.String);
                ylabel(app.UIAxes.YLabel.String);
                title(app.UIAxes.Title.String);
                
                plot(app.lineObj1.XData,app.lineObj1.YData,'-r','LineWidth',1.5);hold on;
                
                try
                    if length(app.lineObj2.YData) > 1
                        plot(app.lineObj2.XData,app.lineObj2.YData,'-g','LineWidth',1.5);
                    end
                end
                
                try
                    if length(app.lineObj3.YData) > 1
                        plot(app.lineObj3.XData,app.lineObj3.YData,'-c','LineWidth',1.5);
                    end
                end
                
                try
                    if length(app.lineObj4.YData) > 1
                        plot(app.lineObj4.XData,app.lineObj4.YData,'-b','LineWidth',1.5);
                    end
                end
                
                try
                    if length(app.lineObj5.YData) > 1
                        plot(app.lineObj5.XData,app.lineObj5.YData,'-m','LineWidth',1.5);
                    end
                end
                
                try
                    if length(app.lineObj6.YData) > 1
                        plot(app.lineObj6.XData,app.lineObj6.YData,'-b','LineWidth',1.5);
                    end
                end
                hold off;
                
            elseif app.pattern1 == '4'
                figure(4);
                
                % 调整x坐标
                xmin = app.XminEditField.Value;
                xmax = app.XmaxEditField.Value;
                if xmin < xmax
                    app.UIAxes.XLim = [xmin xmax];
                end
                 % 调整y坐标
                ymin = app.YminEditField.Value;
                ymax = app.YmaxEditField.Value;
                if ymin < ymax
                    app.UIAxes.YLim = [ymin ymax];
                end
          
                imagesc(app.lineObj1.CData);
                xlabel("Pixel Number");
                ylabel("Pixel Number");
                title(app.UIAxes.Title.String);
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            % 退出程序
            app.delete()
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.SifListListBox.Items = "";
            AllDataInit(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main = uifigure;
            app.Main.Name = 'Matlab Sif Read Plot v2.0';
            app.Main.SizeChangedFcn = createCallbackFcn(app, @MainSizeChanged, true);
            app.FileMenu = uimenu(app.Main);
            app.FileMenu.Text = 'File';
            app.OpenFileMenu = uimenu(app.FileMenu);
            app.OpenFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenFileMenu.Separator = 'on';
            app.OpenFileMenu.Accelerator = 'F';
            app.OpenFileMenu.Text = 'Open File';
            app.Save0asMenu = uimenu(app.FileMenu);
            app.Save0asMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save0asMenu.Separator = 'on';
            app.Save0asMenu.Accelerator = 'S';
            app.Save0asMenu.Text = 'Save #0 as';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'Q';
            app.ExitMenu.Text = 'Exit';
            app.ListMenu = uimenu(app.Main);
            app.ListMenu.Text = 'List';
            app.AddFileinHeadMenu = uimenu(app.ListMenu);
            app.AddFileinHeadMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFileinHeadMenuSelected, true);
            app.AddFileinHeadMenu.Accelerator = 'O';
            app.AddFileinHeadMenu.Text = 'Add File in Head';
            app.AddFileinEnddingMenu = uimenu(app.ListMenu);
            app.AddFileinEnddingMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFileinEnddingMenuSelected, true);
            app.AddFileinEnddingMenu.Text = 'Add File in Endding';
            app.ReplaceFileListMenu = uimenu(app.ListMenu);
            app.ReplaceFileListMenu.MenuSelectedFcn = createCallbackFcn(app, @ReplaceFileListMenuSelected, true);
            app.ReplaceFileListMenu.Text = 'Replace File List';
            app.DeleteCurrentFileMenu = uimenu(app.ListMenu);
            app.DeleteCurrentFileMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCurrentFileMenuSelected, true);
            app.DeleteCurrentFileMenu.Separator = 'on';
            app.DeleteCurrentFileMenu.Text = 'Delete Current File';
            app.DeleteAllFileMenu = uimenu(app.ListMenu);
            app.DeleteAllFileMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteAllFileMenuSelected, true);
            app.DeleteAllFileMenu.Text = 'Delete All File';
            app.WindowsMenu = uimenu(app.Main);
            app.WindowsMenu.Text = 'Windows';
            app.CloseAllWindowsMenu = uimenu(app.WindowsMenu);
            app.CloseAllWindowsMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAllWindowsMenuSelected, true);
            app.CloseAllWindowsMenu.Accelerator = 'Q';
            app.CloseAllWindowsMenu.Text = 'Close All Windows';
            app.ProcessMenu = uimenu(app.Main);
            app.ProcessMenu.Text = 'Process';
            app.SubtractBackgroundMenu = uimenu(app.ProcessMenu);
            app.SubtractBackgroundMenu.Text = 'Subtract Background';
            app.HelpMenu = uimenu(app.Main);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.Main);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.TabGroup2 = uitabgroup(app.MainTab);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = '#0';
            app.Panel_2 = uipanel(app.Tab);
            app.OKButton_2 = uibutton(app.Panel_2, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Text = 'OK';
            app.YminEditFieldLabel = uilabel(app.Panel_2);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.Panel_2, 'numeric');
            app.YmaxEditFieldLabel = uilabel(app.Panel_2);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.Tab);
            app.OKButton = uibutton(app.Panel_3, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.XminEditFieldLabel = uilabel(app.Panel_3);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.Panel_3, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.Panel_3);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.Panel_3, 'numeric');
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel_4 = uipanel(app.Tab);
            app.CommandLineButton_2 = uibutton(app.Panel_4, 'state');
            app.CommandLineButton_2.ValueChangedFcn = createCallbackFcn(app, @CommandLineButton_2ValueChanged, true);
            app.CommandLineButton_2.Text = 'Command Line';
            app.runButton = uibutton(app.Panel_4, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.Panel_5 = uipanel(app.Tab);
            app.SaveButton = uibutton(app.Panel_5, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CommandLinePanel = uipanel(app.Tab);
            app.CommandLinePanel.Title = 'Command Line';
            app.CommandLinePanel.Visible = 'off';
            app.EditField = uieditfield(app.CommandLinePanel, 'text');
            app.ExecuteButton = uibutton(app.CommandLinePanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.SetTab = uitab(app.TabGroup);
            app.SetTab.Title = 'Set';
            app.CalculationPanel = uipanel(app.SetTab);
            app.CalculationPanel.Title = 'Calculation';
            app.DropDown_2 = uidropdown(app.CalculationPanel);
            app.DropDown_2.Items = {''};
            app.DropDown_2.Value = '';
            app.DropDown_3 = uidropdown(app.CalculationPanel);
            app.DropDown_3.Items = {''};
            app.DropDown_3.Value = '';
            app.Label = uilabel(app.CalculationPanel);
            app.Label.Text = '=';
            app.DropDown_4 = uidropdown(app.CalculationPanel);
            app.DropDown_4.Items = {'+', '-', 'x', '/'};
            app.DropDown_4.Value = '-';
            app.ApplyButton = uibutton(app.CalculationPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Label_2 = uilabel(app.CalculationPanel);
            app.Label_2.Text = '#0';
            app.DropDown_6 = uidropdown(app.CalculationPanel);
            app.DropDown_6.Items = {'+', '-', 'x', '/'};
            app.DropDown_6.Value = '-';
            app.EditField_2 = uieditfield(app.CalculationPanel, 'numeric');
            app.HoldButton = uibutton(app.CalculationPanel, 'state');
            app.HoldButton.ValueChangedFcn = createCallbackFcn(app, @HoldButtonValueChanged, true);
            app.HoldButton.Text = 'Hold';
            app.Panel = uipanel(app.Main);
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ClearButton = uibutton(app.Panel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SifListListBoxLabel = uilabel(app.Panel);
            app.SifListListBoxLabel.Text = 'Sif List ';
            app.SifListListBox = uilistbox(app.Panel);
            app.SifListListBox.Items = {''};
            app.SifListListBox.Multiselect = 'on';
            app.SifListListBox.ValueChangedFcn = createCallbackFcn(app, @SifListListBoxValueChanged, true);
            app.SifListListBox.Value = {''};
            app.CloseAllButton = uibutton(app.Panel, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllWindowsMenuSelected, true);
            app.CloseAllButton.Text = 'Close All';
            app.SaveButton_2 = uibutton(app.Panel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton_2.Text = 'Save';
            app.CMDButton = uibutton(app.Panel, 'push');
            app.CMDButton.ButtonPushedFcn = createCallbackFcn(app, @CommandLineButton_2ValueChanged, true);
            app.CMDButton.Text = 'CMD';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MatlabSifRead

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main)
        end
    end
end